classdef Digital_number_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        DigitalnumberrecognitionsystemDevelopedbyGroup16Label  matlab.ui.control.Label
        RecognizednumberEditField       matlab.ui.control.NumericEditField
        RecognizednumberEditFieldLabel  matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        RecordButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function dist = DTW(app,t,r)
            n = size(t,1);
            m = size(r,1);
            % 帧匹配距离矩阵
            d = zeros(n,m);
            for i = 1:n
                for j = 1:m
                    d(i,j) = sum((t(i,:)-r(j,:)).^2);
                end
            end
            % 累积距离矩阵
            D = ones(n,m) * realmax;
            D(1,1) = d(1,1);
            % 动态规划
            for i = 2:n
                for j = 1:m
                    D1 = D(i-1,j);
                    if j>1
                     D2 = D(i-1,j-1);
                    else
                     D2 = realmax;
                end
                if j>2
                    D3 = D(i-1,j-2);
                else
                    D3 = realmax;
                end
                D(i,j) = d(i,j) + min([D1,D2,D3]);
                end
            end
            dist = D(n,m);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            format = 16; % 16位整数
            channels = 1; % 声道数（单声道）
            rate = 8000; % 采样率
            chunk = 1024; % 分块大小
            record_seconds = 3; % 录制时长
            name = 'User';
            % 获取用户桌面目录
            desktop = winqueryreg('HKEY_CURRENT_USER', ...
            'Software\Microsoft\Windows\CurrentVersion\Explorer\Shell Folders', 'Desktop');
            % 创建输出文件夹
            output_folder = fullfile(desktop, 'User');
            if ~exist(output_folder, 'dir')
            mkdir(output_folder);
            end
            output_filename = fullfile(output_folder, [name, '.wav']);
            recorder = audiorecorder(rate, format, channels);
            app.TextArea_2.Value = "Recording biginned";
            % 录制音频
            app.Lamp.Color = 'g';
            recordblocking(recorder, record_seconds);
            app.TextArea_2.Value = "Recording stopped";
            % 停止并保存录音
            stop(recorder);
            audiowrite(output_filename, getaudiodata(recorder), rate);
            app.Lamp.Color = 'r';
            [x1,fs]=audioread('C:\Users\26303\Desktop\User\User.wav');
            %绘制出录音波形图
            plot(app.UIAxes,x1,"b");
            
            %生成输入语音信号文件的input_voice
            quan_start
            S1 = load('C:\Users\26303\Desktop\app\input_voice.mat');
            BP1 = struct2cell(S1);
            input_voice = cell2mat(BP1);


            %加载标准语音模版库
            %std = [];
            for i=0:9
                for j=1:10
                    filename = ['C:\Users\26303\Desktop\app\model' , '\model' , '_' , num2str(i) , '_' , num2str(j) , '.mat']; 
                    S1 = load(filename);
                    BP1 = struct2cell(S1);
                    std{10*i+j} = cell2mat(BP1);
                end
            end

            %计算输入语音文件与标准语音模版之间的距离 最小值算法
%             calculate_distance = [];
%             for i=0:9
%                 count = realmax;
%                 for j=1:10
%                     if(DTW(app,input_voice,std{10*i+j}) < count)
%                        count = DTW(app,input_voice,std{10*i+j});
%                     end
%                 end
%                 calculate_distance(i+1) = count;
%             end
%             %输出最相似的数字编号
%             [min_a,recognized_num]=min(calculate_distance,[],2);
%             app.RecognizednumberEditField.Value = recognized_num-1;


              %计算输入语音文件与标准语音模版之间的距离 平均值算法
            calculate_distance = [];
            for i=0:9
                count = 0;
                for j=1:10
                       count = count + DTW(app,input_voice,std{10*i+j});
                end
                calculate_distance(i+1) = count/10;
            end
            %输出最相似的数字编号
            [min_a,recognized_num]=min(calculate_distance,[],2);
            app.RecognizednumberEditField.Value = recognized_num-1;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Value changed function: RecognizednumberEditField
        function RecognizednumberEditFieldValueChanged(app, event)
            
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            delete(app);
            clear app;
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            app.TextArea_2.Value = "";
            app.RecognizednumberEditField.Value = 0;
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'icon1.jpg';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'icon2.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input voice wave')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Lamp = uilamp(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.RecognizednumberEditFieldLabel = uilabel(app.UIFigure);
            app.RecognizednumberEditFieldLabel.Text = 'Recognized number';
            app.RecognizednumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecognizednumberEditField.ValueChangedFcn = createCallbackFcn(app, @RecognizednumberEditFieldValueChanged, true);
            app.DigitalnumberrecognitionsystemDevelopedbyGroup16Label = uilabel(app.UIFigure);
            app.DigitalnumberrecognitionsystemDevelopedbyGroup16Label.Text = {'Digital number recognition system'; ' Developed by Group 16'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Digital_number_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end